classdef imagegeneratorclassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imagegeneratorclassifierUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompositeImageGeneratorTab    matlab.ui.container.Tab
        InputandOutputPanel           matlab.ui.container.Panel
        InputImageButton              matlab.ui.control.Button
        OutputImageButton             matlab.ui.control.Button
        GoButton                      matlab.ui.control.Button
        OptionsPanel                  matlab.ui.container.Panel
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        TileCountEditFieldLabel       matlab.ui.control.Label
        TileCountEditField            matlab.ui.control.NumericEditField
        ProgressGaugeLabel            matlab.ui.control.Label
        ProgressGauge                 matlab.ui.control.LinearGauge
        BinaryImageClassifierTab      matlab.ui.container.Tab
        TrainingImageLocationsPanel   matlab.ui.container.Panel
        ManmadeTrainingButton         matlab.ui.control.Button
        NaturalTrainingButton         matlab.ui.control.Button
        TestImageLocationsPanel       matlab.ui.container.Panel
        ManmadeTestButton             matlab.ui.control.Button
        NaturalTestButton             matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        InfoTextAreaLabel             matlab.ui.control.Label
        InfoTextArea                  matlab.ui.control.TextArea
        kEditFieldLabel               matlab.ui.control.Label
        kEditField                    matlab.ui.control.NumericEditField
        HelpTab                       matlab.ui.container.Tab
        CompositeImageGeneratorPanel  matlab.ui.container.Panel
        BinaryImageClassifierPanel    matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        EditField                     matlab.ui.control.EditField
    end


    properties (Access = private)
        % COMPOSITE IMAGE GENERATOR
        % put properties here
        % BINARY IMAGE CLASSIFIER 
        trainManmadeDir = '';
        trainNaturalDir = '';
        testManmadeDir = '';
        testNaturalDir = '';
        kVal = 4;
    end

    methods (Access = private)
    
        function appendTextArea(app, text)
             app.InfoTextArea.Value = [app.InfoTextArea.Value; text];
             buttonStatus(app);
        end
        
        function buttonStatus(app)
            if isempty(app.trainManmadeDir) || isempty(app.trainNaturalDir) || isempty(app.testManmadeDir) || isempty(app.testNaturalDir)
                app.StartButton.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
          filename = uigetfile;
        end

        % Button pushed function: ManmadeTrainingButton
        function ManmadeTrainingButtonPushed(app, event)
            app.trainManmadeDir = uigetdir('C:\');
            appendTextArea(app, ['Manmade training directory: ' app.trainManmadeDir]);
        end

        % Button pushed function: NaturalTrainingButton
        function NaturalTrainingButtonPushed(app, event)
            app.trainNaturalDir = uigetdir('C:\');
            appendTextArea(app, ['Natural training directory: ' app.trainNaturalDir]);
        end

        % Button pushed function: ManmadeTestButton
        function ManmadeTestButtonPushed(app, event)
            app.testManmadeDir = uigetdir('C:\');
            appendTextArea(app, ['Manmade test directory: ' app.testManmadeDir]);
        end

        % Button pushed function: NaturalTestButton
        function NaturalTestButtonPushed(app, event)
            app.testNaturalDir = uigetdir('C:\');
            appendTextArea(app, ['Natural test directory: ' app.testNaturalDir]);
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            app.kVal = app.kEditField.Value;
        end

        % Value changed function: InfoTextArea
        function InfoTextAreaValueChanged(app, event)
            %val
            %ue = app.InfoTextArea.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            CalculateDifference(app.trainManmadeDir, app.trainNaturalDir, app.testManmadeDir, app.testNaturalDir, app.kVal);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.imagegeneratorclassifierUIFigure = uifigure;
            app.imagegeneratorclassifierUIFigure.Name = 'image-generator-classifier';
            app.TabGroup = uitabgroup(app.imagegeneratorclassifierUIFigure);
            app.CompositeImageGeneratorTab = uitab(app.TabGroup);
            app.CompositeImageGeneratorTab.Title = 'Composite Image Generator';
            app.InputandOutputPanel = uipanel(app.CompositeImageGeneratorTab);
            app.InputandOutputPanel.Title = 'Input and Output';
            app.InputImageButton = uibutton(app.InputandOutputPanel, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.OutputImageButton = uibutton(app.InputandOutputPanel, 'push');
            app.OutputImageButton.Text = 'Output Image';
            app.GoButton = uibutton(app.CompositeImageGeneratorTab, 'push');
            app.GoButton.Text = 'Go!';
            app.OptionsPanel = uipanel(app.CompositeImageGeneratorTab);
            app.OptionsPanel.Title = 'Options';
            app.WidthEditFieldLabel = uilabel(app.OptionsPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.Value = 1;
            app.HeightEditFieldLabel = uilabel(app.OptionsPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.Value = 1;
            app.TileCountEditFieldLabel = uilabel(app.OptionsPanel);
            app.TileCountEditFieldLabel.Text = 'Tile Count';
            app.TileCountEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TileCountEditField.Limits = [1 Inf];
            app.TileCountEditField.Value = 1;
            app.ProgressGaugeLabel = uilabel(app.CompositeImageGeneratorTab);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.CompositeImageGeneratorTab, 'linear');
            app.BinaryImageClassifierTab = uitab(app.TabGroup);
            app.BinaryImageClassifierTab.Title = 'Binary Image Classifier';
            app.TrainingImageLocationsPanel = uipanel(app.BinaryImageClassifierTab);
            app.TrainingImageLocationsPanel.Title = 'Training Image Locations';
            app.ManmadeTrainingButton = uibutton(app.TrainingImageLocationsPanel, 'push');
            app.ManmadeTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @ManmadeTrainingButtonPushed, true);
            app.ManmadeTrainingButton.Text = 'Manmade Training';
            app.NaturalTrainingButton = uibutton(app.TrainingImageLocationsPanel, 'push');
            app.NaturalTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @NaturalTrainingButtonPushed, true);
            app.NaturalTrainingButton.Text = 'Natural Training';
            app.TestImageLocationsPanel = uipanel(app.BinaryImageClassifierTab);
            app.TestImageLocationsPanel.Title = 'Test Image Locations';
            app.ManmadeTestButton = uibutton(app.TestImageLocationsPanel, 'push');
            app.ManmadeTestButton.ButtonPushedFcn = createCallbackFcn(app, @ManmadeTestButtonPushed, true);
            app.ManmadeTestButton.Text = 'Manmade Test';
            app.NaturalTestButton = uibutton(app.TestImageLocationsPanel, 'push');
            app.NaturalTestButton.ButtonPushedFcn = createCallbackFcn(app, @NaturalTestButtonPushed, true);
            app.NaturalTestButton.Text = 'Natural Test';
            app.StartButton = uibutton(app.BinaryImageClassifierTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.InfoTextAreaLabel = uilabel(app.BinaryImageClassifierTab);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.BinaryImageClassifierTab);
            app.InfoTextArea.ValueChangedFcn = createCallbackFcn(app, @InfoTextAreaValueChanged, true);
            app.InfoTextArea.Editable = 'off';
            app.kEditFieldLabel = uilabel(app.BinaryImageClassifierTab);
            app.kEditFieldLabel.Text = 'k = ';
            app.kEditField = uieditfield(app.BinaryImageClassifierTab, 'numeric');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Limits = [1 25];
            app.kEditField.RoundFractionalValues = 'on';
            app.kEditField.ValueDisplayFormat = '%11.2g';
            app.kEditField.Value = 4;
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.CompositeImageGeneratorPanel = uipanel(app.HelpTab);
            app.CompositeImageGeneratorPanel.Title = 'Composite Image Generator';
            app.BinaryImageClassifierPanel = uipanel(app.HelpTab);
            app.BinaryImageClassifierPanel.Title = 'Binary Image Classifier';
            app.TextArea = uitextarea(app.BinaryImageClassifierPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The binary image classifier allows you to to classify manmade and natural images according to an inputed training set.'; ''; 'To utilise this functionality, first select the manmade and natural training sets that you would like to use. Do this by selecting the directory that contains these images. After this, you will perform the same operation to select the manmade and natural test sets that you would like to use with the classifier.'; ''; 'The classifier operates using a k-nearest neighbour classifier. You may specify k yourself for any value from 1 to 25. The default value is 4. '; ''; 'Press the Start button to begin classification. When classification is complete the results box shall show you the total computation time, misclassification rates for each class and the total accuracy.'};
            app.EditField = uieditfield(app.imagegeneratorclassifierUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Team 3 - Iain Morton and Daniel Kinnaird                        https://github.com/AC41002-team3/image-generator-classifier';
        end
    end

    methods (Access = public)

        % Construct app
        function app = imagegeneratorclassifier

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imagegeneratorclassifierUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imagegeneratorclassifierUIFigure)
        end
    end
end